classdef learnJapanese < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        show_CN         matlab.ui.control.CheckBox
        show_ans        matlab.ui.control.CheckBox
        mode_selection  matlab.ui.control.DropDown
        Label           matlab.ui.control.Label
        check_num       matlab.ui.control.Spinner
        Label_3         matlab.ui.control.Label
        submit_button   matlab.ui.control.Button
        check_button    matlab.ui.control.Button
        check_table     matlab.ui.control.Table
    end

    
    properties (Access = private)
        numCheck        %how many words to check
        last_num_check  %how many words checked last time
        word            %word lib struct list
        numWords        %length of word
        word_w_hanzi    %word_w_hanzi lib struct list
        numWords_w_hanzi %length of word_w_hanzi
        mode            %check mode
        ran_ID          %ID of words checked
        grey_sty = uistyle('BackgroundColor',[0.94,0.94,0.94]);
        white_sty = uistyle('BackgroundColor',[1.00,1.00,1.00]);
        green_sty = uistyle('BackgroundColor','green');
        red_dty = uistyle('BackgroundColor','red');
        sPass = uistyle("Icon","success","IconAlignment","rightmargin");
        sFail = uistyle("Icon","error","IconAlignment","rightmargin");
        false_list      %list of false numbers
        filepath        %path of wordlib
    end
    
    methods (Access = private)
        function lib_init(app)
            %读取文件
            wordlib = readcell(app.filepath);
            [numRows, ~] = size(wordlib);
            app.numWords = numRows - 2;
            
            % 检查是否有 missing 类型的数据并将其替换为空字符串或其他合适的值
            for i = 1:numel(wordlib)
                if ismissing(wordlib{i})
                    wordlib{i} = '';  % 将 missing 替换为空字符串
                end
            end
            %
            
            %获取中文词库
            chinese_lib = wordlib(3:numRows, 1);
            
            %获取假名词库
            JP_lib = wordlib(3:numRows, 2);
            
            %获取汉字词库
            hanzi_lib = wordlib(3:numRows, 3);
            
            %获取错误次数
            false_lib = wordlib(3:numRows, 4);
            
            % 初始化结构体数组
            app.word = struct('chinese', [], 'JP', [], 'hanzi', [], 'false', [], 'ID', [], 'has_hanzi', [], 'position', []);
            
            %结构体赋值
            for i = 1:app.numWords
                app.word(i).chinese = chinese_lib{i};
                app.word(i).JP = JP_lib{i};
                app.word(i).hanzi = hanzi_lib{i};
                app.word(i).false = false_lib{i};
                app.word(i).ID = i;
                if hanzi_lib{i} == '／'
                    app.word(i).has_hanzi = 0;
                else
                    app.word(i).has_hanzi = 1;
                end
                app.word(i).position = [i 1];
            end
            
            %创建仅包含有汉字结构体数组
            app.word_w_hanzi = struct('chinese', [], 'JP', [], 'hanzi', [], 'false', [], 'ID', [], 'has_hanzi', [], 'position', []);
            k = 1;
            for i = 1:app.numWords
                if app.word(i).has_hanzi == 1
                    app.word_w_hanzi(k) = app.word(i);
                    k = k + 1;
                end
            end

            app.numWords_w_hanzi = k-1;

            %选择抽查模式和数量（即使打开app后这两个回调函数没有执行也可以使用默认值
            change_mode(app, []);
            change_check_num(app, []);

        
            %初始化抽查表格
            app.check_table.Data = cell(app.numCheck, 4);
            app.check_table.ColumnName = {'中文', '假名', '汉字', '答案'};

            addStyle(app.check_table, app.grey_sty, 'column', [1,2,3,4]);

            app.last_num_check = 10;
        end
        
        function table_generate(app)

            rng('shuffle'); % set current time as random seed

            %清空表格
            for i = 1:app.last_num_check
                [app.check_table.Data{i, 1}, app.check_table.Data{i, 2}, app.check_table.Data{i, 3}] = deal('', '', '');
            end
            
            app.last_num_check = app.numCheck;   
            
            removeStyle(app.check_table);

            %中文抽查
            if app.mode == 1
                app.check_table.ColumnName = {'中文', '假名', '汉字', '答案'};
                %中文随机
                addStyle(app.check_table, app.grey_sty, 'column', [1,4]);
                addStyle(app.check_table, app.white_sty, 'column', [2,3]);
                app.ran_ID = randperm(app.numWords, app.numCheck);
                for i = 1:app.numCheck
                    app.check_table.Data{i, 1} = app.word(app.ran_ID(i)).chinese;
                    if strcmp(app.word(app.ran_ID(i)).hanzi, '／')
                        app.check_table.Data{i, 3} = '／';
                        addStyle(app.check_table, app.grey_sty, 'cell', [i, 3]);
                    end
                end
            end
        
            %假名抽查
            if app.mode == 2
                app.check_table.ColumnName = {'假名', '汉字', '中文', '答案'};
                %假名随机
                app.ran_ID = randperm(app.numWords, app.numCheck);
                for i = 1:app.numCheck
                    app.check_table.Data{i, 1} = app.word(app.ran_ID(i)).JP;
                end
                addStyle(app.check_table, app.grey_sty, 'column', [1,3,4]);
                addStyle(app.check_table, app.white_sty, 'column', 2);
            end
        
            %汉字抽查
            if app.mode == 3
                app.check_table.ColumnName = {'汉字', '假名', '中文', '答案'};
                %汉字随机
                app.ran_ID = randperm(app.numWords_w_hanzi, app.numCheck);
                for i = 1:app.numCheck
                    app.check_table.Data{i, 1} = app.word_w_hanzi(app.ran_ID(i)).hanzi;
                end
                addStyle(app.check_table, app.grey_sty, 'column', [1,3,4]);
                addStyle(app.check_table, app.white_sty, 'column', 2);
            end

            %初始化输入区
            if app.mode == 1
                app.check_table.ColumnEditable = [false, true, true, false];
            else
                app.check_table.ColumnEditable = [false, true, false, false];
            end
        end
        
        function check_submission(app)

            if app.mode == 1
                false_flag = 0;
                for i = 1:app.numCheck
                    if strcmp(app.check_table.Data{i, 2}, app.word(app.ran_ID(i)).JP)
                        %addStyle(app.check_table, t, 'cell', [i, 2]);
                        addStyle(app.check_table,app.sPass,"cell",[i, 2]);
                    else
                        app.word(app.ran_ID(i)).false = app.word(app.ran_ID(i)).false + 1;
                        %addStyle(app.check_table, f, 'cell', [i, 2]);
                        addStyle(app.check_table,app.sFail,"cell",[i, 2]);
                        false_flag = 1;
                    end
                    if strcmp(app.check_table.Data{i, 3}, app.word(app.ran_ID(i)).hanzi)
                        %addStyle(app.check_table, t, 'cell', [i, 2]);
                        addStyle(app.check_table,app.sPass,"cell",[i, 3]);
                    else
                        app.word(app.ran_ID(i)).false = app.word(app.ran_ID(i)).false + 1 - false_flag;
                        %addStyle(app.check_table, f, 'cell', [i, 2]);
                        addStyle(app.check_table,app.sFail,"cell",[i, 3]);
                    end
                    false_flag = 0;
                end
            elseif app.mode == 2
                for i = 1:app.numCheck
                    if strcmp(app.check_table.Data{i, 2}, app.word(app.ran_ID(i)).hanzi)
                        %addStyle(app.check_table, t, 'cell', [i, 2]);
                        addStyle(app.check_table,app.sPass,"cell",[i, 2]);
                    else
                        app.word(app.ran_ID(i)).false = app.word(app.ran_ID(i)).false + 1;
                        %addStyle(app.check_table, f, 'cell', [i, 2]);
                        addStyle(app.check_table,app.sFail,"cell",[i, 2]);
                    end
                end 
            else
                for i = 1:app.numCheck
                    if strcmp(app.check_table.Data{i, 2}, app.word(app.ran_ID(i)).JP)
                        %addStyle(app.check_table, t, 'cell', [i, 2]);
                        addStyle(app.check_table,app.sPass,"cell",[i, 2]);
                    else
                        app.word(app.ran_ID(i)).false = app.word(app.ran_ID(i)).false + 1;
                        %addStyle(app.check_table, f, 'cell', [i, 2]);
                        addStyle(app.check_table,app.sFail,"cell",[i, 2]);
                    end
                end 
            end

            %update false time
            app.false_list = zeros(app.numWords, 1);
            for i = 1:app.numWords
                app.false_list(i) = app.word(i).false;
            end
            writematrix(app.false_list, app.filepath, 'Range', 'D3');

        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.filepath = fullfile(pwd, 'wordlib.xlsx');  % 在应用当前目录下寻找文件
    
            
            lib_init(app);
        end

        % Button pushed function: check_button
        function check_generate(app, event)
            for i = 1:app.last_num_check
                app.check_table.Data{i, 3} = '';
            end
            table_generate(app);
            app.submit_button.Enable = "on";
            app.show_ans.Enable = "off";
            app.mode_selection.Enable = "off";
            app.show_ans.Value = 0;
            app.show_CN.Enable = "off";
            app.show_CN.Value = 0;
            for i = 1:app.last_num_check
                app.check_table.Data{i, 4} = '';
            end

        end

        % Value changed function: check_num
        function change_check_num(app, event)

            app.numCheck = app.check_num.Value;
         
        end

        % Value changed function: mode_selection
        function change_mode(app, event)
            app.mode = int8(app.mode_selection.Value) - 48;
        end

        % Button pushed function: submit_button
        function submit(app, event)
            check_submission(app);
            app.submit_button.Enable = "off";
            app.show_ans.Enable = "on";
            app.mode_selection.Enable = "on";
            if app.mode == 2 || app.mode ==3
                app.show_CN.Enable = "on";
            end
        end

        % Value changed function: show_ans
        function showanswer(app, event)
            value = app.show_ans.Value;
            if value
                if app.mode == 1 || app.mode == 3
                    for i = 1:app.numCheck
                        app.check_table.Data{i, 4} = app.word(app.ran_ID(i)).JP;
                    end
                else
                    for i = 1:app.numCheck
                        app.check_table.Data{i, 4} = app.word(app.ran_ID(i)).hanzi;
                    end
                end
            else
                for i = 1:app.last_num_check
                    app.check_table.Data{i, 4} = '';
                end
            end
        end

        % Value changed function: show_CN
        function showCN(app, event)
            value = app.show_CN.Value;
            if value
                for i = 1:app.numCheck
                    app.check_table.Data{i, 3} = app.word(app.ran_ID(i)).chinese;
                end
            else
                for i = 1:app.last_num_check
                    app.check_table.Data{i, 3} = '';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.check_table = uitable(app.UIFigure);
            app.check_table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.check_table.RowName = {};
            app.check_button = uibutton(app.UIFigure, 'push');
            app.check_button.ButtonPushedFcn = createCallbackFcn(app, @check_generate, true);
            app.check_button.Text = '开始抽查！';
            app.submit_button = uibutton(app.UIFigure, 'push');
            app.submit_button.ButtonPushedFcn = createCallbackFcn(app, @submit, true);
            app.submit_button.Enable = 'off';
            app.submit_button.Text = '提交';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '抽查数量';
            app.check_num = uispinner(app.UIFigure);
            app.check_num.Limits = [0 30];
            app.check_num.ValueChangedFcn = createCallbackFcn(app, @change_check_num, true);
            app.check_num.Value = 10;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '显示';
            app.mode_selection = uidropdown(app.UIFigure);
            app.mode_selection.Items = {'中文', '假名', '汉字'};
            app.mode_selection.ItemsData = {'1', '2', '3'};
            app.mode_selection.ValueChangedFcn = createCallbackFcn(app, @change_mode, true);
            app.mode_selection.Value = '1';
            app.show_ans = uicheckbox(app.UIFigure);
            app.show_ans.ValueChangedFcn = createCallbackFcn(app, @showanswer, true);
            app.show_ans.Enable = 'off';
            app.show_ans.Text = '显示答案';
            app.show_CN = uicheckbox(app.UIFigure);
            app.show_CN.ValueChangedFcn = createCallbackFcn(app, @showCN, true);
            app.show_CN.Enable = 'off';
            app.show_CN.Text = '显示中文';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = learnJapanese

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end